classdef envelopes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image                         matlab.ui.control.Image
        EnvolventePanel               matlab.ui.container.Panel
        GraficarButton                matlab.ui.control.Button
        NumerodetramosEditField       matlab.ui.control.NumericEditField
        NumerodetramosEditFieldLabel  matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        cEditField                    matlab.ui.control.NumericEditField
        cEditFieldLabel               matlab.ui.control.Label
        bEditField                    matlab.ui.control.NumericEditField
        bEditFieldLabel               matlab.ui.control.Label
        aEditField                    matlab.ui.control.NumericEditField
        aEditFieldLabel               matlab.ui.control.Label
        LongitudesmLabel              matlab.ui.control.Label
        CargaskNLabel                 matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        MuertaDEditField              matlab.ui.control.NumericEditField
        MuertaDEditFieldLabel         matlab.ui.control.Label
        VivaLEditField                matlab.ui.control.NumericEditField
        VivaLEditFieldLabel           matlab.ui.control.Label
        TipodeenvolventeButtonGroup   matlab.ui.container.ButtonGroup
        CortanteButton                matlab.ui.control.RadioButton
        MomentoButton                 matlab.ui.control.RadioButton
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            basePath = fileparts(mfilename('fullpath'));
            addpath(fullfile(basePath, '..', 'core', 'models'));

            isShearSelected = strcmp(app.TipodeenvolventeButtonGroup.SelectedObject.Text, 'Cortante');
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            c = app.cEditField.Value;
            l = app.VivaLEditField.Value;
            d = app.MuertaDEditField.Value;
            n = app.NumerodetramosEditField.Value;
            beam = DoubleOverhangingBeam(a,c,b,Load(l,d));
            
            if isShearSelected
                addpath(fullfile(basePath, '..', 'core', 'functions', 'shear'));
            else
                addpath(fullfile(basePath, '..', 'core', 'functions', 'moment'));
            end

            cla(app.UIAxes)
            if(isShearSelected)
                shear_envelope(beam, n, true, app.UIAxes);
            else
                moment_envelope(beam, n, true, app.UIAxes);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Envolvente de momento')
            xlabel(app.UIAxes, 'Longitud (m)')
            ylabel(app.UIAxes, 'Momento (kNm)')
            zlabel(app.UIAxes, 'Z')
            app.EnvolventePanel = uipanel(app.UIFigure);
            app.EnvolventePanel.Title = 'Envolvente';
            app.TipodeenvolventeButtonGroup = uibuttongroup(app.EnvolventePanel);
            app.TipodeenvolventeButtonGroup.Title = 'Tipo de envolvente';
            app.MomentoButton = uiradiobutton(app.TipodeenvolventeButtonGroup);
            app.MomentoButton.Text = 'Momento';
            app.MomentoButton.Value = true;
            app.CortanteButton = uiradiobutton(app.TipodeenvolventeButtonGroup);
            app.CortanteButton.Text = 'Cortante';
            app.VivaLEditFieldLabel = uilabel(app.EnvolventePanel);
            app.VivaLEditFieldLabel.Text = 'Viva (L)';
            app.VivaLEditField = uieditfield(app.EnvolventePanel, 'numeric');
            app.VivaLEditField.Value = 3;
            app.MuertaDEditFieldLabel = uilabel(app.EnvolventePanel);
            app.MuertaDEditFieldLabel.Text = 'Muerta (D)';
            app.MuertaDEditField = uieditfield(app.EnvolventePanel, 'numeric');
            app.MuertaDEditField.Value = 1;
            app.Label = uilabel(app.EnvolventePanel);
            app.Label.Text = '______________________________________________';
            app.CargaskNLabel = uilabel(app.EnvolventePanel);
            app.CargaskNLabel.Text = 'Cargas (kN)';
            app.LongitudesmLabel = uilabel(app.EnvolventePanel);
            app.LongitudesmLabel.Text = 'Longitudes (m)';
            app.aEditFieldLabel = uilabel(app.EnvolventePanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.EnvolventePanel, 'numeric');
            app.aEditField.Limits = [0 Inf];
            app.aEditField.Value = 1;
            app.bEditFieldLabel = uilabel(app.EnvolventePanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.EnvolventePanel, 'numeric');
            app.bEditField.Limits = [0.1 Inf];
            app.bEditField.Value = 4;
            app.cEditFieldLabel = uilabel(app.EnvolventePanel);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.EnvolventePanel, 'numeric');
            app.cEditField.Limits = [0 Inf];
            app.cEditField.Value = 2;
            app.Label_2 = uilabel(app.EnvolventePanel);
            app.Label_2.Text = '______________________________________________';
            app.NumerodetramosEditFieldLabel = uilabel(app.EnvolventePanel);
            app.NumerodetramosEditFieldLabel.Text = 'Numero de tramos ';
            app.NumerodetramosEditField = uieditfield(app.EnvolventePanel, 'numeric');
            app.NumerodetramosEditField.Limits = [1 Inf];
            app.NumerodetramosEditField.Value = 3;
            app.GraficarButton = uibutton(app.EnvolventePanel, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'beam_properties.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = envelopes

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end